classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        CalculatePlotButton        matlab.ui.control.Button
        ComponentsLabel            matlab.ui.control.Label
        CalculatedparametersLabel  matlab.ui.control.Label
        UITable2                   matlab.ui.control.Table
        UITable                    matlab.ui.control.Table
        FitButton                  matlab.ui.control.Button
        UIAxes3                    matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            t = table([10; 0.5; 8000; 0.5; 4005], 'RowNames', {'Hcr1'; 'mcr1'; 'Hcr2'; 'mcr2'; 'Hx'});
            app.UITable2.Data = t{:,:};
            app.UITable2.RowName = t.Properties.RowNames;

            %t = table({' '; ' '; ' '; ' '; ' '; ' '}, 'RowNames', {'a'; 'αMs'; 'Ms'; 'α'; 'αMs/(3a)'; 'Hk'});
            %app.UITable.Data = t{:,:};
            %app.UITable.RowName = t.Properties.RowNames;
        end

        % Button pushed function: FitButton
        function FitButtonPushed(app, event)
            N = 100;

            utils = Utils();

            [H, M] = Parser('data/MvsH - (JNEX-900, AIP advances).csv').get_data_csv;

            dMdH = transpose(gradient(M(:)) ./ gradient(H(:)));

            HdMdH = H.*dMdH;

            [HTip, MTip] = utils.find_tip(H, M);

            Hcr = [8.749 6881.3];
            mcr = [0.457 0.49];

            magnetic_parameters = MagneticParameters(H, M, Hcr, mcr);
            a = magnetic_parameters.get_a();
            alphaMs = magnetic_parameters.get_alphaMs(a);
            Ms = magnetic_parameters.get_Ms(a, alphaMs);
            alpha = magnetic_parameters.get_alpha(alphaMs, Ms);
            dimensionless_alphaMs = magnetic_parameters.get_dimensionless_alphaMs(alphaMs, a);
            Hk = magnetic_parameters.get_Hk(alphaMs, a);

            Hhat = logspace(log10(H(2)),log10(HTip),N);
            Mhat = get_Mhat(Hhat, a, alphaMs, Ms);
            dMdHhat = get_dMdHhat(Hhat, alpha, Ms, a, alphaMs);
            HdMdHhat = get_HdMdHhat(Hhat, dMdHhat);

            plotter = Plotter(H, M, dMdH, HdMdH, Hhat, Mhat, dMdHhat, HdMdHhat, Hcr);
            plotter.plot_M(app.UIAxes);
            plotter.plot_dMdH(app.UIAxes2);
            plotter.plot_HdMdH(app.UIAxes3);
            t = table([a(1), a(2); alphaMs(1), alphaMs(2); Ms(1), Ms(2); alpha(1), alpha(2); dimensionless_alphaMs(1), dimensionless_alphaMs(2); Hk(1), Hk(2)], 'RowNames', {'a'; 'αMs'; 'Ms'; 'α'; 'αMs/(3a)'; 'Hk'});
            app.UITable.Data = t{:,:};
            app.UITable.RowName = t.Properties.RowNames;
        end

        % Button pushed function: CalculatePlotButton
        function CalculatePlotButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'H (A/m)')
            ylabel(app.UIAxes, 'M (A/m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'H (A/m)')
            ylabel(app.UIAxes2, '∂M/∂H')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes3, 'H (A/m)')
            ylabel(app.UIAxes3, 'H ∂M/∂H (A/m)')
            zlabel(app.UIAxes3, 'Z')
            app.FitButton = uibutton(app.UIFigure, 'push');
            app.FitButton.ButtonPushedFcn = createCallbackFcn(app, @FitButtonPushed, true);
            app.FitButton.Text = 'Fit';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Component 1'; 'Component 2'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Seed'};
            app.UITable2.RowName = {};
            app.UITable2.ColumnEditable = true;
            app.CalculatedparametersLabel = uilabel(app.UIFigure);
            app.CalculatedparametersLabel.Text = 'Calculated parameters';
            app.ComponentsLabel = uilabel(app.UIFigure);
            app.ComponentsLabel.Text = 'Components';
            app.CalculatePlotButton = uibutton(app.UIFigure, 'push');
            app.CalculatePlotButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatePlotButtonPushed, true);
            app.CalculatePlotButton.Text = 'Calculate & Plot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end